classdef GroupSixChainCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        TextAreaLabel                 matlab.ui.control.Label
        SaveChainCodeButton           matlab.ui.control.Button
        NormalizedChainCodeEditField  matlab.ui.control.EditField
        NormalizedChainCodeEditFieldLabel  matlab.ui.control.Label
        DifferentatedChainCodeEditField  matlab.ui.control.EditField
        DifferentatedChainCodeEditFieldLabel  matlab.ui.control.Label
        ChainCodeEditField            matlab.ui.control.EditField
        ChainCodeEditFieldLabel       matlab.ui.control.Label
        NormalizeChainCodeButton      matlab.ui.control.Button
        DifferentiateChainCodeButton  matlab.ui.control.Button
        ExtractChainCodeButton        matlab.ui.control.Button
        ExtractFeaturesButton         matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Load an image from files
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp;*.tif','Image Files'}, 'Select an image file');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                % Read the selected image
                originalImage = imread(fullfile(path, file));
                % Display the image in UIAxes
                imshow(originalImage, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: ExtractFeaturesButton
        function ExtractFeaturesButtonPushed(app, event)
            originalImage = getimage(app.UIAxes);
            [edgeImage, thinnedEdgeImage] = detectEdges(originalImage);
        
            imshow(thinnedEdgeImage, 'Parent', app.UIAxes2);

            function [edgeImage, thinnedEdgeImage] = detectEdges(image)
                % Convert the image to grayscale if it's RGB
                if size(image, 3) == 3
                    grayImage = rgb2gray(image);
                else
                    grayImage = image;
                end
                
                % Detect edges using the Canny method
                edgeImage = edge(grayImage, 'Canny');
                
                % Perform morphological thinning to ensure one-pixel-wide boundary
                thinnedEdgeImage = bwmorph(edgeImage, 'thin', Inf);
            end
        end

        % Button pushed function: ExtractChainCodeButton
        function ExtractChainCodeButtonPushed(app, event)
            thinnedEdgeImage = getimage(app.UIAxes2);

            chainCode = traverseBoundaryWithCodes(thinnedEdgeImage);
            chainCodeStr = num2str(chainCode);
            app.ChainCodeEditField.Value = chainCodeStr;

            function chainCode = traverseBoundaryWithCodes(thinnedEdgeImage)
                % Find the starting pixel (P0)
                [row, col] = find(thinnedEdgeImage, 1, 'first');
                startingPixel = [row, col];
                currentPixel = startingPixel;
                
                % Initialize direction
                DIR = 7;
                
                % Define directions
                directions = [0, -1; -1, -1; -1, 0; -1, 1; 0, 1; 1, 1; 1, 0; 1, -1];
                codeValues = [4, 3, 2, 1, 0, 5, 6, 7]; % Corresponding values for directions
                
                % Traverse the boundary
                chainCode = [];
                while true
                    % Find the direction of the next white pixel
                    nextDir = mod(DIR + 7, 8);
                    found = false;
                    for i = 1:8
                        nextPixel = currentPixel + directions(nextDir + 1, :);
                        if thinnedEdgeImage(nextPixel(1), nextPixel(2))
                            found = true;
                            break;
                        else
                            nextDir = mod(nextDir + 1, 8);
                        end
                    end
                    
                    % Break if the starting pixel is reached again and chainCode is not empty
                    if isequal(nextPixel, startingPixel) && ~isempty(chainCode)
                        break;
                    end
                    
                    % Assign the code value
                    chainCode = [chainCode, codeValues(nextDir + 1)];
                    
                    % Move to the next pixel
                    currentPixel = nextPixel;
                    DIR = nextDir;
                end
            end    
        end

        % Button pushed function: DifferentiateChainCodeButton
        function DifferentiateChainCodeButtonPushed(app, event)
            % Retrieve the chain code from the ChainCodeEditField
            chainCodeStr = app.ChainCodeEditField.Value;
            
            % Convert chain code string to numerical array
            chainCode = str2num(chainCodeStr); % str2num converts string to numerical array
            
            % Differentiate the chain code
            diffChainCode = differentiateChainCode(chainCode);
            
            % Convert the differentiated chain code to string for display
            diffChainCodeStr = num2str(diffChainCode);
            
            % Display the differentiated chain code in the DifferentatedChainCodeEditField
            app.DifferentatedChainCodeEditField.Value = diffChainCodeStr;
        
            % Nested function to differentiate the chain code
            function diffChainCode = differentiateChainCode(chainCode)
                    n = length(chainCode);
                    diffChainCode = zeros(1, n);
                    for k = 1:n
                        ck = chainCode(k);
                        ck_1 = chainCode(mod(k-2, n) + 1); % ck-1 (with wrapping)
                        diffChainCode(k) = mod(ck - ck_1, 8);
                    end
            end
        end

        % Button pushed function: NormalizeChainCodeButton
        function NormalizeChainCodeButtonPushed(app, event)
            diffChainCodeStr = app.DifferentatedChainCodeEditField.Value;
            diffChainCode = str2num(diffChainCodeStr);
        
            normalizedCodes = normalizeChainCodes(diffChainCode);
            sortedCodes = sortrows(normalizedCodes);
        
            app.NormalizedChainCodeEditField.Value = num2str(sortedCodes(1, :));
        
            % Display normalizedCodes in TextArea
            normalizedCodesStr = '';
            for i = 1:size(normalizedCodes, 1)
                normalizedCodesStr = [normalizedCodesStr mat2str(sortedCodes(i, :)) newline];
            end
            app.TextArea.Value = normalizedCodesStr;
        
            function normalizedCodes = normalizeChainCodes(chainCode)
                n = length(chainCode);
                normalizedCodes = zeros(n, n);  % Preallocate matrix to store all permutations
                for i = 1:n
                    % Rotate the chain code to the left by i-1 positions
                    normalizedCodes(i, :) = circshift(chainCode, -i + 1);
                end
            end
        end

        % Button pushed function: SaveChainCodeButton
        function SaveChainCodeButtonPushed(app, event)
               % Get the necessary data from UI elements or app properties
            thinnedEdgeImage = getimage(app.UIAxes2);
            chainCodeStr = app.ChainCodeEditField.Value;
            diffChainCodeStr = app.DifferentatedChainCodeEditField.Value;
            normalizedChainCodeStr = app.NormalizedChainCodeEditField.Value;
            
            % Convert chain codes from strings to numerical arrays
            chainCode = str2num(chainCodeStr);
            diffChainCode = str2num(diffChainCodeStr);
            normalizedChainCode = str2num(normalizedChainCodeStr);
            
            % Get the normalized chain codes from the TextArea
            normalizedCodesStr = app.TextArea.Value;
            normalizedCodes = str2double(split(string(normalizedCodesStr)));
            
            % Get the desired output filename from the user
            outputFilename = inputdlg('Enter the output filename:', 'Save As', [1 50]);
            
            % Check if the user provided a filename
            if ~isempty(outputFilename)
                % Call the writeOutputsToFile function to save the data
                writeOutputsToFile(outputFilename{1}, thinnedEdgeImage, chainCode, diffChainCode, normalizedChainCode, normalizedCodes);
                % Provide feedback to the user that the data has been saved
                msgbox('Chain codes and images have been saved successfully', 'Save Successful', 'modal');
            else
                % Provide feedback to the user that saving was cancelled
                msgbox('Saving cancelled', 'Cancelled', 'warn', 'modal');
            end
                
            function writeOutputsToFile(filename, thinnedEdgeImage, chainCode, diffChainCode, normalizedChainCode, normalizedCodes)
                fid = fopen(filename, 'w');
                
                % Write thinned edge image dimensions
                fprintf(fid, 'Thinned Edge Image Dimensions:\n');
                fprintf(fid, 'Height: %d, Width: %d\n\n', size(thinnedEdgeImage, 1), size(thinnedEdgeImage, 2));
                
                % Write chain code
                fprintf(fid, 'Chain Code:\n');
                fprintf(fid, '%s\n\n', num2str(chainCode));
                
                % Write differential chain code
                fprintf(fid, 'Differential Chain Code:\n');
                fprintf(fid, '%s\n\n', num2str(diffChainCode));
                
                % Write normalized chain code
                fprintf(fid, 'Normalized Chain Code:\n');
                fprintf(fid, '%s\n\n', num2str(normalizedChainCode));
                
                % Write sorted normalized chain codes
                fprintf(fid, 'Sorted Normalized Chain Codes:\n');
                for i = 1:size(normalizedCodes, 1)
                    fprintf(fid, '%s\n', num2str(normalizedCodes(i, :)));
                end
                
                fclose(fid);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Binarized Edge Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ExtractFeaturesButton = uibutton(app.UIFigure, 'push');
            app.ExtractFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFeaturesButtonPushed, true);
            app.ExtractFeaturesButton.Text = 'Extract Features';
            app.ExtractChainCodeButton = uibutton(app.UIFigure, 'push');
            app.ExtractChainCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractChainCodeButtonPushed, true);
            app.ExtractChainCodeButton.Text = 'Extract Chain Code';
            app.DifferentiateChainCodeButton = uibutton(app.UIFigure, 'push');
            app.DifferentiateChainCodeButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentiateChainCodeButtonPushed, true);
            app.DifferentiateChainCodeButton.Text = 'Differentiate Chain Code';
            app.NormalizeChainCodeButton = uibutton(app.UIFigure, 'push');
            app.NormalizeChainCodeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeChainCodeButtonPushed, true);
            app.NormalizeChainCodeButton.Text = 'Normalize Chain Code';
            app.ChainCodeEditFieldLabel = uilabel(app.UIFigure);
            app.ChainCodeEditFieldLabel.Text = 'Chain Code';
            app.ChainCodeEditField = uieditfield(app.UIFigure, 'text');
            app.DifferentatedChainCodeEditFieldLabel = uilabel(app.UIFigure);
            app.DifferentatedChainCodeEditFieldLabel.Text = 'Differentated Chain Code';
            app.DifferentatedChainCodeEditField = uieditfield(app.UIFigure, 'text');
            app.NormalizedChainCodeEditFieldLabel = uilabel(app.UIFigure);
            app.NormalizedChainCodeEditFieldLabel.Text = 'Normalized Chain Code';
            app.NormalizedChainCodeEditField = uieditfield(app.UIFigure, 'text');
            app.SaveChainCodeButton = uibutton(app.UIFigure, 'push');
            app.SaveChainCodeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChainCodeButtonPushed, true);
            app.SaveChainCodeButton.Text = 'Save Chain Code';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroupSixChainCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end